classdef app1_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Menu                matlab.ui.container.Menu
        SairMenu            matlab.ui.container.Menu
        RelatrioMenu        matlab.ui.container.Menu
        CreditsMenu         matlab.ui.container.Menu
        AutorMenu           matlab.ui.container.Menu
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        AtualizarButton     matlab.ui.control.Button
        BottomGroupMethods  matlab.ui.container.ButtonGroup
        TodosB              matlab.ui.control.RadioButton
        ODE45B              matlab.ui.control.RadioButton
        HeunsB              matlab.ui.control.RadioButton
        RK4B                matlab.ui.control.RadioButton
        RK3B                matlab.ui.control.RadioButton
        RK2B                matlab.ui.control.RadioButton
        EulerMB             matlab.ui.control.RadioButton
        EulerB              matlab.ui.control.RadioButton
        DadosIniciaisPanel  matlab.ui.container.Panel
        eY0                 matlab.ui.control.NumericEditField
        y0EditFieldLabel    matlab.ui.control.Label
        eN                  matlab.ui.control.NumericEditField
        nEditFieldLabel     matlab.ui.control.Label
        eB                  matlab.ui.control.NumericEditField
        bEditFieldLabel     matlab.ui.control.Label
        eF                  matlab.ui.control.EditField
        ftyEditFieldLabel   matlab.ui.control.Label
        eA                  matlab.ui.control.NumericEditField
        aEditFieldLabel     matlab.ui.control.Label
        RightPanel          matlab.ui.container.Panel
        UITable             matlab.ui.control.Table
        UIAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AtualizarButton
        function AtualizarButtonPushed(app, event)
            
            syms y(t)
            strF = app.eF.Value;
            f =@(t,y) eval(vectorize(strF));
            a = app.eA.Value;
            b = app.eB.Value;
            n = app.eN.Value;
            y0 = app.eY0.Value;

            methodchoice = app.BottomGroupMethods.SelectedObject;
            method = find([app.EulerB,...
                app.EulerMB...
                app.RK2B...
                app.RK3B...
                app.RK4B...
                app.HeunsB...
                app.ODE45B...
                app.TodosB]==methodchoice);


            try


                yEuler = MEuler(f,a,b,n,y0);
                yEulerM = MEulerM(f,a,b,n,y0);
                yRK2 = MRK2(f,a,b,n,y0);
                yRK3 = MRK3(f,a,b,n,y0);
                yRK4 = MRK4(f,a,b,n,y0);
                yHeuns = MHeuns(f,a,b,n,y0);
                [tODE45,yODE45] = ODE45(f,a,b,n,y0);
                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);


                g =@(t) eval(vectorize(char(sExata)));

                h = (b-a)/n;
                t = a:h:b;
                yExata = g(t);
                erroEuler = abs(yExata-yEuler);
                erroEulerM = abs(yExata-yEulerM);
                erroRK2 = abs(yExata-yRK2);
                erroRK3 = abs(yExata-yRK3);
                erroRK4 = abs(yExata-yRK4);
                erroHeuns = abs(yExata-yHeuns);
                erroODE45 = abs(yExata-yODE45);
                plot(app.UIAxes,t,yExata,"-ro")
                hold(app.UIAxes,"on")


                switch method
                    case 1  
                        plot(app.UIAxes,t,yEuler,"-g*")
                        legenda = {'Exata','Euler'};
                        tabela = [t.',yExata.',yEuler.',erroEuler.'];
                        nomeColunas = {'t','Exata','Euler','erroEuler'};
                    case 2
                        plot(app.UIAxes,t,yEulerM,"-r*")
                        legenda = {'Exata','EulerM'};
                        tabela = [t.',yExata.',yEulerM.',erroEulerM.'];
                        nomeColunas = {'t','Exata','Euler Melhorado','erroEulerM'};
                    case 3
                        plot(app.UIAxes,t,yRK2,"-b*")
                        legenda = {'Exata','RK2'};
                        tabela = [t.',yExata.',yRK2.',erroRK2.'];
                        nomeColunas = {'t','Exata','RK2','erroRK2'};
                    case 4
                        plot(app.UIAxes,t,yRK3,"-c*")
                        legenda = {'Exata','RK3'};
                        tabela = [t.',yExata.',yRK3.',erroRK3.'];
                        nomeColunas = {'t','Exata','RK3','erroRK3'};
                    case 5
                        plot(app.UIAxes,t,yRK4,"-m*")
                        legenda = {'Exata','RK4'};
                        tabela = [t.',yExata.',yRK4.',erroRK4.'];
                        nomeColunas = {'t','Exata','RK4','erroRK4'};
                    case 6
                        plot(app.UIAxes,t,yHeuns,"-y*")
                        legenda = {'Exata','Heuns'};
                        tabela = [t.',yExata.',yHeuns.',erroHeuns.'];
                        nomeColunas = {'t','Exata','Heuns','erroHeuns'};
                    case 7
                        plot(app.UIAxes,t,yODE45,"-k*")
                        legenda = {'Exata','ODE45'};
                        tabela = [t.',yExata.',[tODE45,yODE45],erroODE45];
                        nomeColunas = {'t','Exata','ODE45','erroODE45'};
                    case 8
                        plot(app.UIAxes,t,yEuler,"-g*")
                        plot(app.UIAxes,t,yEulerM,"-r*")
                        plot(app.UIAxes,t,yRK2,"-b*")
                        plot(app.UIAxes,t,yRK3,"-c*")
                        plot(app.UIAxes,t,yRK4,"-m*")
                        plot(app.UIAxes,t,yHeuns,"-y*")
                        plot(app.UIAxes,t,yODE45,"-k*")
                        legenda = {'Exata','Euler','EulerM','RK2','RK3','RK4','Heuns','ODE45'};
                        tabela = [t.',yExata.',yEuler.',yEulerM.',yRK2.',yRK3.',yRK4.',yHeuns.',[tODE45,yODE45]...
                            erroEuler.',erroEulerM.',erroRK2.',erroRK3.',erroRK4.',erroHeuns.',erroODE45];
                        nomeColunas = {'t','Exata','Euler','EulerM','RK2','RK3','RK4','Heuns','ODE45',...
                            'erroEuler','erroEulerM','erroRK2','erroRK3','erroRK4','erroHeuns','erroODE45'};
                end

                
                T = array2table(tabela);
                app.UITable.Data = T;
                app.UITable.ColumnName = nomeColunas;
                hold(app.UIAxes,"off")
                grid(app.UIAxes,"on")
                legend(app.UIAxes,legenda)
            catch ME
                errordlg(ME.message,"Erro","modal")
            end
           
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer mesmo sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.UIFigure)
            end
        end

        % Menu selected function: AutorMenu
        function AutorMenuSelected(app, event)
            Autor
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            web()
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {635, 635};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {248, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.SairMenu = uimenu(app.Menu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.RelatrioMenu = uimenu(app.UIFigure);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.CreditsMenu = uimenu(app.UIFigure);
            app.CreditsMenu.Text = 'Credits';
            app.AutorMenu = uimenu(app.CreditsMenu);
            app.AutorMenu.MenuSelectedFcn = createCallbackFcn(app, @AutorMenuSelected, true);
            app.AutorMenu.Text = 'Autor';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosIniciaisPanel = uipanel(app.LeftPanel);
            app.DadosIniciaisPanel.Title = 'Dados Iniciais';
            app.aEditFieldLabel = uilabel(app.DadosIniciaisPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosIniciaisPanel, 'numeric');
            app.ftyEditFieldLabel = uilabel(app.DadosIniciaisPanel);
            app.ftyEditFieldLabel.Text = 'f(t,y) =';
            app.eF = uieditfield(app.DadosIniciaisPanel, 'text');
            app.eF.Placeholder = 'Insert differential equation';
            app.bEditFieldLabel = uilabel(app.DadosIniciaisPanel);
            app.bEditFieldLabel.Text = 'b';
            app.eB = uieditfield(app.DadosIniciaisPanel, 'numeric');
            app.eB.Value = 1;
            app.nEditFieldLabel = uilabel(app.DadosIniciaisPanel);
            app.nEditFieldLabel.Text = 'n';
            app.eN = uieditfield(app.DadosIniciaisPanel, 'numeric');
            app.eN.Value = 5;
            app.y0EditFieldLabel = uilabel(app.DadosIniciaisPanel);
            app.y0EditFieldLabel.Text = 'y0';
            app.eY0 = uieditfield(app.DadosIniciaisPanel, 'numeric');
            app.eY0.Value = 1;
            app.BottomGroupMethods = uibuttongroup(app.LeftPanel);
            app.BottomGroupMethods.Title = 'Métodos Numéricos';
            app.EulerB = uiradiobutton(app.BottomGroupMethods);
            app.EulerB.Text = 'Euler';
            app.EulerB.Value = true;
            app.EulerMB = uiradiobutton(app.BottomGroupMethods);
            app.EulerMB.Text = 'Euler M.';
            app.RK2B = uiradiobutton(app.BottomGroupMethods);
            app.RK2B.Text = 'RK2';
            app.RK3B = uiradiobutton(app.BottomGroupMethods);
            app.RK3B.Text = 'RK3';
            app.RK4B = uiradiobutton(app.BottomGroupMethods);
            app.RK4B.Text = 'RK4';
            app.HeunsB = uiradiobutton(app.BottomGroupMethods);
            app.HeunsB.Text = 'Heuns';
            app.ODE45B = uiradiobutton(app.BottomGroupMethods);
            app.ODE45B.Text = 'ODE45';
            app.TodosB = uiradiobutton(app.BottomGroupMethods);
            app.TodosB.Text = 'Todos';
            app.AtualizarButton = uibutton(app.LeftPanel, 'push');
            app.AtualizarButton.ButtonPushedFcn = createCallbackFcn(app, @AtualizarButtonPushed, true);
            app.AtualizarButton.Text = 'Atualizar';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end