classdef appMaquinaPVI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        OpesMenu                    matlab.ui.container.Menu
        SairMenu                    matlab.ui.container.Menu
        RelatrioMenu                matlab.ui.container.Menu
        CrditosMenu                 matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        ResetButton                 matlab.ui.control.Button
        AtualizarButton             matlab.ui.control.Button
        BottomGroupMethods          matlab.ui.container.ButtonGroup
        TodosB                      matlab.ui.control.RadioButton
        ODE45B                      matlab.ui.control.RadioButton
        HeunsB                      matlab.ui.control.RadioButton
        RK4B                        matlab.ui.control.RadioButton
        RK3B                        matlab.ui.control.RadioButton
        RK2B                        matlab.ui.control.RadioButton
        EulerMB                     matlab.ui.control.RadioButton
        EulerB                      matlab.ui.control.RadioButton
        DadosIniciaisparaoPVIPanel  matlab.ui.container.Panel
        eY0                         matlab.ui.control.NumericEditField
        y0EditFieldLabel            matlab.ui.control.Label
        eN                          matlab.ui.control.NumericEditField
        nEditFieldLabel             matlab.ui.control.Label
        eB                          matlab.ui.control.NumericEditField
        bEditFieldLabel             matlab.ui.control.Label
        eF                          matlab.ui.control.EditField
        ftyEditFieldLabel           matlab.ui.control.Label
        eA                          matlab.ui.control.NumericEditField
        aEditFieldLabel             matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        Trabalho01MtodosnumricosparaaresoluodeEDOPVILabel  matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AtualizarButton
        function AtualizarButtonPushed(app, event)
            
            syms y(t)
            strF = app.eF.Value;
            f =@(t,y) eval(vectorize(strF));
            a = app.eA.Value;
            b = app.eB.Value;
            n = app.eN.Value;
            y0 = app.eY0.Value;

            methodchoice = app.BottomGroupMethods.SelectedObject;
            method = find([app.EulerB,...
                app.EulerMB...
                app.RK2B...
                app.RK3B...
                app.RK4B...
                app.HeunsB...
                app.ODE45B...
                app.TodosB]==methodchoice);

            erroFuncao = MException("MATLAB:bAtualizar:BadF",...
                                "Introduza uma função em t e y");

            erroB = MException("MATLAB:bAtualizar:BadB",...
                           "Introduza um valor de b maior que a");

            erroN = MException("MATLAB:bAtualizar:BadB",...
                           "Introduza um valor válido para n");
        
           erroY0 = MException("MATLAB:bAtualizar:BadB",...
                           "Introduza um valor válido para y0");
            
                try
    
                    try
                        s = f(t,y);
                        app.eF.BackgroundColor = "w";
                    catch
                        app.eF.BackgroundColor = [0.5,0.5,0.5]; % [r,g,b]
                        throw(erroFuncao)
                    end
                
                    if ~(isreal(b) && b>a)
                        app.eB.BackgroundColor = [0.5,0.5,0.5]; % [r,g,b]
                        throw(erroB)   
                    else
                         app.eB.BackgroundColor = [1,1,1]; % [r,g,b]               
                    end

                    if ~(isscalar(n) && isreal(n) && n>0)
                       app.eN.BackgroundColor = [0.5,0.5,0.5]; % [r,g,b]
                       throw(erroN) 
                    else
                        app.eN.BackgroundColor = [1,1,1]; % [r,g,b]   
                    end
                    if ~(isscalar(y0) && isreal(y0))
                        app.eY0.BackgroundColor = [0.5,0.5,0.5]; % [r,g,b]
                        throw(erroY0) 
                    else
                        app.eY0.BackgroundColor = [1,1,1]; % [r,g,b]   
                    end
                    
                    %Aplicação das funções:
                    yEuler = MEuler(f,a,b,n,y0);
                    yEulerM = MEulerM(f,a,b,n,y0);
                    yRK2 = MRK2(f,a,b,n,y0);
                    yRK3 = MRK3(f,a,b,n,y0);
                    yRK4 = MRK4(f,a,b,n,y0);
                    yHeuns = MHeuns(f,a,b,n,y0);
                    [~,yODE45] = ODE45(f,a,b,n,y0);
                    
                    %Calculo da solução exata:
                    sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                    g =@(t) eval(vectorize(char(sExata)));
                    h = (b-a)/n;
                    t = a:h:b;
                    yExata = g(t);

                    %Calculo dos erros:
                    erroEuler = abs(yExata-yEuler);
                    erroEulerM = abs(yExata-yEulerM);
                    erroRK2 = abs(yExata-yRK2);
                    erroRK3 = abs(yExata-yRK3);
                    erroRK4 = abs(yExata-yRK4);
                    erroHeuns = abs(yExata-yHeuns);
                    erroODE45 = abs(yExata-yODE45.');
                    plot(app.UIAxes,t,yExata,"-ro")
                    hold(app.UIAxes,"on")
    
                    %Seleção do método:
                    switch method
                        case 1  
                            plot(app.UIAxes,t,yEuler,"-g*")
                            legenda = {'Exata','Euler'};
                            tabela = [t.',yExata.',yEuler.',erroEuler.'];
                            nomeColunas = {'t','Exata','Euler','Erro'};
                        case 2
                            plot(app.UIAxes,t,yEulerM,"-r*")
                            legenda = {'Exata','EulerM'};
                            tabela = [t.',yExata.',yEulerM.',erroEulerM.'];
                            nomeColunas = {'t','Exata','Euler Melhorado','Erro'};
                        case 3
                            plot(app.UIAxes,t,yRK2,"-b*")
                            legenda = {'Exata','RK2'};
                            tabela = [t.',yExata.',yRK2.',erroRK2.'];
                            nomeColunas = {'t','Exata','RK2','Erro'};
                        case 4
                            plot(app.UIAxes,t,yRK3,"-c*")
                            legenda = {'Exata','RK3'};
                            tabela = [t.',yExata.',yRK3.',erroRK3.'];
                            nomeColunas = {'t','Exata','RK3','Erro'};
                        case 5
                            plot(app.UIAxes,t,yRK4,"-m*")
                            legenda = {'Exata','RK4'};
                            tabela = [t.',yExata.',yRK4.',erroRK4.'];
                            nomeColunas = {'t','Exata','RK4','Erro'};
                        case 6
                            plot(app.UIAxes,t,yHeuns,"-y*")
                            legenda = {'Exata','Heuns'};
                            tabela = [t.',yExata.',yHeuns.',erroHeuns.'];
                            nomeColunas = {'t','Exata','Heuns','Erro'};
                        case 7
                            plot(app.UIAxes,t,yODE45,"-k*")
                            legenda = {'Exata','ODE45'};
                            tabela = [t.',yExata.',yODE45,erroODE45.'];
                            nomeColunas = {'t','Exata','ODE45','Erro'};
                        case 8
                            plot(app.UIAxes,t,yEuler,"-g*")
                            plot(app.UIAxes,t,yEulerM,"-r*")
                            plot(app.UIAxes,t,yHeuns,"-y*")
                            plot(app.UIAxes,t,yRK2,"-b*")
                            plot(app.UIAxes,t,yRK3,"-c*")
                            plot(app.UIAxes,t,yRK4,"-m*")
                            plot(app.UIAxes,t,yODE45,"-k*")
                            legenda = {'Exata','Euler','EulerM','Heuns','RK2','RK3','RK4','ODE45'};
                            tabela = [t.',yExata.',yEuler.',yEulerM.',yHeuns.',yRK2.',yRK3.',yRK4.',yODE45, erroEuler.',erroEulerM.',erroHeuns.',erroRK2.',erroRK3.',erroRK4.',erroODE45.'];
                            nomeColunas = {'t','Exata','Euler','EulerM','Heuns','RK2','RK3','RK4','ODE45','Erro - Euler','Erro - EulerM','Erro - Heuns','Erro - RK2','Erro - RK3','Erro - RK4','Erro - ODE45'};
                                
                    end
    
                    
                    T = array2table(tabela);
                    app.UITable.Data = T;
                    app.UITable.ColumnName = nomeColunas;
                    hold(app.UIAxes,"off")  
                    grid(app.UIAxes,"on")
                    legend(app.UIAxes,legenda)
               catch ME
                   errordlg(ME.message,"Erro","modal")
               end
            
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Deseja mesmo sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.UIFigure)
            end
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            open 'Relatório At01 MNEDO.pdf'
        end

        % Menu selected function: CrditosMenu
        function CrditosMenuSelected(app, event)
            Autor
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.eF.Value = '';
            app.eA.Value = 0;
            app.eB.Value = 0;
            app.eN.Value = 0;
            app.eY0.Value = 0;
            app.UITable.Data = {};
            cla(app.UIAxes)
            app.UITable.ColumnName = {};
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            SairMenuSelected(app, []); 
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {635, 635};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {281, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.OpesMenu = uimenu(app.UIFigure);
            app.OpesMenu.Text = 'Opções';
            app.SairMenu = uimenu(app.OpesMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.RelatrioMenu = uimenu(app.UIFigure);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.CrditosMenu = uimenu(app.UIFigure);
            app.CrditosMenu.MenuSelectedFcn = createCallbackFcn(app, @CrditosMenuSelected, true);
            app.CrditosMenu.Text = 'Créditos';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosIniciaisparaoPVIPanel = uipanel(app.LeftPanel);
            app.DadosIniciaisparaoPVIPanel.Title = 'Dados Iniciais para o PVI';
            app.aEditFieldLabel = uilabel(app.DadosIniciaisparaoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosIniciaisparaoPVIPanel, 'numeric');
            app.ftyEditFieldLabel = uilabel(app.DadosIniciaisparaoPVIPanel);
            app.ftyEditFieldLabel.Text = 'f(t,y) =';
            app.eF = uieditfield(app.DadosIniciaisparaoPVIPanel, 'text');
            app.eF.Placeholder = 'Insira a equação diferencial';
            app.bEditFieldLabel = uilabel(app.DadosIniciaisparaoPVIPanel);
            app.bEditFieldLabel.Text = 'b';
            app.eB = uieditfield(app.DadosIniciaisparaoPVIPanel, 'numeric');
            app.nEditFieldLabel = uilabel(app.DadosIniciaisparaoPVIPanel);
            app.nEditFieldLabel.Text = 'n';
            app.eN = uieditfield(app.DadosIniciaisparaoPVIPanel, 'numeric');
            app.eN.Limits = [0 Inf];
            app.y0EditFieldLabel = uilabel(app.DadosIniciaisparaoPVIPanel);
            app.y0EditFieldLabel.Text = 'y0';
            app.eY0 = uieditfield(app.DadosIniciaisparaoPVIPanel, 'numeric');
            app.BottomGroupMethods = uibuttongroup(app.LeftPanel);
            app.BottomGroupMethods.Title = 'Selecione um Método Numérico: ';
            app.EulerB = uiradiobutton(app.BottomGroupMethods);
            app.EulerB.Text = 'Euler';
            app.EulerB.Value = true;
            app.EulerMB = uiradiobutton(app.BottomGroupMethods);
            app.EulerMB.Text = 'Euler M.';
            app.RK2B = uiradiobutton(app.BottomGroupMethods);
            app.RK2B.Text = 'RK2';
            app.RK3B = uiradiobutton(app.BottomGroupMethods);
            app.RK3B.Text = 'RK3';
            app.RK4B = uiradiobutton(app.BottomGroupMethods);
            app.RK4B.Text = 'RK4';
            app.HeunsB = uiradiobutton(app.BottomGroupMethods);
            app.HeunsB.Text = 'Heuns';
            app.ODE45B = uiradiobutton(app.BottomGroupMethods);
            app.ODE45B.Text = 'ODE45';
            app.TodosB = uiradiobutton(app.BottomGroupMethods);
            app.TodosB.Text = 'Todos';
            app.AtualizarButton = uibutton(app.LeftPanel, 'push');
            app.AtualizarButton.ButtonPushedFcn = createCallbackFcn(app, @AtualizarButtonPushed, true);
            app.AtualizarButton.Text = 'Atualizar';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Gráfico da Função')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.Trabalho01MtodosnumricosparaaresoluodeEDOPVILabel = uilabel(app.RightPanel);
            app.Trabalho01MtodosnumricosparaaresoluodeEDOPVILabel.Text = 'Trabalho 01 - Métodos numéricos para a resolução de EDO/PVI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaPVI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end